classdef shivank_sharma_final_project_caffeine_calculator_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RestLeftLabel                matlab.ui.control.Label
        MinLeftLabel                 matlab.ui.control.Label
        MinSinceLabel                matlab.ui.control.Label
        NeglibleHalfLivesLabel       matlab.ui.control.Label
        NeglibleTimeLabel            matlab.ui.control.Label
        SymptomsLabel                matlab.ui.control.Label
        FDAWarningLabel              matlab.ui.control.Label
        SafetyAssessmentLabel        matlab.ui.control.Label
        PercentagesLabel             matlab.ui.control.Label
        EstimatedLevelsLabel         matlab.ui.control.Label
        ConsumptionLabel             matlab.ui.control.Label
        SafetyRatingLamp             matlab.ui.control.Lamp
        SafetyLabel                  matlab.ui.control.Label
        MaxLimitPercentGauge         matlab.ui.control.Gauge
        MaxLimitPercentGaugeLabel    matlab.ui.control.Label
        SafeLimitPercentGauge        matlab.ui.control.Gauge
        SafeLimitPercentGaugeLabel   matlab.ui.control.Label
        CalculateButton              matlab.ui.control.Button
        EnterEstimatedTimesinceIngestionminEditField  matlab.ui.control.NumericEditField
        EnterEstimatedTimesinceIngestionminEditFieldLabel  matlab.ui.control.Label
        Tocalculatelbstokgdividelbsby2205Label  matlab.ui.control.Label
        EnterWeightkgEditField       matlab.ui.control.NumericEditField
        EnterWeightkgEditFieldLabel  matlab.ui.control.Label
        EnterEstimatedCaffeinemgEditField  matlab.ui.control.NumericEditField
        EnterEstimatedCaffeinemgEditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %input variables
            cafIntake = app.EnterEstimatedCaffeinemgEditField.Value;
            bodyWeight = app.EnterWeightkgEditField.Value;
            elapsedTime = app.EnterEstimatedTimesinceIngestionminEditField.Value;

            %fixing if Time = 0
            if elapsedTime == 0
                elapsedTime = 1;
            end
            
            % calculating safety limits
            safe_limit = bodyWeight * 3;
            max_limit = bodyWeight * 6;

            %indicating safety level
            if cafIntake <= safe_limit
                app.SafetyRatingLamp.Color = [0.00, 1.00, 0.00];
                app.SafetyAssessmentLabel.Text = "You are within your safe limits, and should not experience any negative symptoms."
            elseif cafIntake > safe_limit && cafIntake <= max_limit
                app.SafetyRatingLamp.Color = [1.00, 1.00, 0.00];
                app.SafetyAssessmentLabel.Text = "You have exceeded your safe limits! While you are within your max limits, you may experience some negative symptoms!"
            else
                app.SafetyRatingLamp.Color = [1.00, 0.00, 0.00];
                app.SafetyAssessmentLabel.Text = "YOU HAVE EXCEEDED YOUR MAX LIMITS!!! IT IS HIGHLY LIKELY YOU WILL EXPERIENCE NEGATIVE SYMPTOMS!!!"
            end

            %indicating safety percentages
            percent_safe = (cafIntake / safe_limit) * 100;
            app.SafeLimitPercentGauge.Value = percent_safe;
            percent_max  = (cafIntake / max_limit) * 100;
            app.MaxLimitPercentGauge.Value = percent_max;

            %Determining number of minutes until Caffeine reaches 30 mg
            %(negligble amounts)
            caf_effectTime = round(342 * (log(30 / cafIntake) / log(0.5))) + 1;
            k = (5.7 * 60) / log(2);

            %data collection and storage
            for i = elapsedTime:caf_effectTime
                if i <= 45
                    caf_remains(i) = i * (cafIntake / 45);
                else
                    caf_remains(i) = cafIntake * exp((-1 * i) / k);
                end
            end

            % determing values for displaying statistics
            endTimeHours = caf_effectTime / 60;
            num_Halflives = endTimeHours / 5.7;
            minLeft = caf_effectTime - elapsedTime;
            hoursLeft = minLeft / 60;
            half_livesLeft = hoursLeft / 5.7;

            % displaying written statistics
            app.ConsumptionLabel.Text = "You have consumed " + num2str(cafIntake) + " mg of caffeine."
            app.EstimatedLevelsLabel.Text = "Your estimated daily safe and max levels are " + num2str(safe_limit) + " mg and " + num2str(max_limit) + " mg."
            app.PercentagesLabel.Text = "You have consumed " + num2str(percent_safe) + "% of daily safe limit, and " + num2str(percent_max) + "% of daily max level."
            app.FDAWarningLabel.Text = "According to the FDA, the average American is suggested to keep their caffeine intake below 400 mg."
            app.SymptomsLabel.Text = "Please consult a doctor if you experience negative symptoms, such as insomnia, anxiety, chest pain, headaches, or irregular heartbeat."
            app.NeglibleTimeLabel.Text = "It will take approximately " + num2str(caf_effectTime) + " minutes, or approximately " + num2str(endTimeHours) + " hours to reduce caffeine levels to neglible effects."
            app.NeglibleHalfLivesLabel.Text = "This is approximately " + num2str(num_Halflives) + " half-lives."
            app.MinSinceLabel.Text = "It has been " + num2str(elapsedTime) + " minutes since you ingested caffeine."
            app.MinLeftLabel.Text = "It will take " + num2str(minLeft) + " more minutes until it is safe to consume other medications."
            app.RestLeftLabel.Text = "This is approximately " + num2str(hoursLeft) + " hours, or " + num2str(half_livesLeft) + " half-lives."

            % graph
            app.UIAxes.XLim = [0, (caf_effectTime + 100)]
            app.UIAxes.YLim = [0, (cafIntake + 100)]
            plot(app.UIAxes,caf_remains)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Blood Caffeine Concentration Decay over Time')
            xlabel(app.UIAxes, 'Time (in min)')
            ylabel(app.UIAxes, 'Caffeine Concentration (mg)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ZTick = [];
            app.EnterEstimatedCaffeinemgEditFieldLabel = uilabel(app.UIFigure);
            app.EnterEstimatedCaffeinemgEditFieldLabel.Text = 'Enter Estimated Caffeine (mg)';
            app.EnterEstimatedCaffeinemgEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterWeightkgEditFieldLabel = uilabel(app.UIFigure);
            app.EnterWeightkgEditFieldLabel.Text = 'Enter Weight (kg) ';
            app.EnterWeightkgEditField = uieditfield(app.UIFigure, 'numeric');
            app.Tocalculatelbstokgdividelbsby2205Label = uilabel(app.UIFigure);
            app.Tocalculatelbstokgdividelbsby2205Label.Text = 'To calculate lbs to kg, divide lbs by 2.205';
            app.EnterEstimatedTimesinceIngestionminEditFieldLabel = uilabel(app.UIFigure);
            app.EnterEstimatedTimesinceIngestionminEditFieldLabel.Text = 'Enter Estimated Time since Ingestion (min)';
            app.EnterEstimatedTimesinceIngestionminEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SafeLimitPercentGaugeLabel = uilabel(app.UIFigure);
            app.SafeLimitPercentGaugeLabel.Text = 'Safe Limit Percent';
            app.SafeLimitPercentGauge = uigauge(app.UIFigure, 'circular');
            app.MaxLimitPercentGaugeLabel = uilabel(app.UIFigure);
            app.MaxLimitPercentGaugeLabel.Text = 'Max Limit Percent';
            app.MaxLimitPercentGauge = uigauge(app.UIFigure, 'circular');
            app.SafetyLabel = uilabel(app.UIFigure);
            app.SafetyLabel.Text = 'Safety Rating';
            app.SafetyRatingLamp = uilamp(app.UIFigure);
            app.ConsumptionLabel = uilabel(app.UIFigure);
            app.ConsumptionLabel.Text = 'Consumption:';
            app.EstimatedLevelsLabel = uilabel(app.UIFigure);
            app.EstimatedLevelsLabel.Text = 'Estimated Levels:';
            app.PercentagesLabel = uilabel(app.UIFigure);
            app.PercentagesLabel.Text = 'Percentages:';
            app.SafetyAssessmentLabel = uilabel(app.UIFigure);
            app.SafetyAssessmentLabel.Text = 'Safety Assessment:';
            app.FDAWarningLabel = uilabel(app.UIFigure);
            app.FDAWarningLabel.Text = 'FDA Warning:';
            app.SymptomsLabel = uilabel(app.UIFigure);
            app.SymptomsLabel.Text = 'Symptoms:';
            app.NeglibleTimeLabel = uilabel(app.UIFigure);
            app.NeglibleTimeLabel.Text = 'Neglible Time:';
            app.NeglibleHalfLivesLabel = uilabel(app.UIFigure);
            app.NeglibleHalfLivesLabel.Text = 'Neglible Half-Lives';
            app.MinSinceLabel = uilabel(app.UIFigure);
            app.MinSinceLabel.Text = 'Min Since:';
            app.MinLeftLabel = uilabel(app.UIFigure);
            app.MinLeftLabel.Text = 'Min Left:';
            app.RestLeftLabel = uilabel(app.UIFigure);
            app.RestLeftLabel.Text = 'Rest Left:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shivank_sharma_final_project_caffeine_calculator_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end